classdef CascadeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow        matlab.ui.Figure                    % KASKADA
        TabGroup          matlab.ui.container.TabGroup        % System, Kas...
        TabBox            matlab.ui.container.Tab             % System
        LoadButton        matlab.ui.control.Button            % Wczytaj model
        DrawButton        matlab.ui.control.Button            % Rysuj system
        ListBox           matlab.ui.control.ListBox           % case9, case...
        Label             matlab.ui.control.Label             % Nazwa modelu
        Tab               matlab.ui.container.Tab             % Kaskada
        TypeKnob          matlab.ui.control.DiscreteKnob      % Wszystkie, ...
        StartButton       matlab.ui.control.Button            % START
        TextArea          matlab.ui.control.TextArea         
        NinetyDegreeGauge matlab.ui.control.NinetyDegreeGauge % [0 100]
        Label2            matlab.ui.control.Label             % Poziom zasi...
        Label3            matlab.ui.control.Label             % odbiorców
        ModelKnob         matlab.ui.control.DiscreteKnob      % AC, DC, MIX
    end

    
    properties (Access = private)
        mpr % mpr
        mpc
        hPlot
        hGraph
        hBar
        hFigure
        XData
        YData
        NodeName
        result
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ListBox
            app.MainWindow.Position = getPosition('menu', 400, 400);
            app.TabGroup.Position(3) = app.MainWindow.Position(3);
            app.TabGroup.Position(4) = app.MainWindow.Position(4);
            app.hFigure = figure;
            app.hFigure.Position = getPosition('graph', 400, 400)
            app.hFigure.IntegerHandle = 'off';
            app.hFigure.Name = 'Okno wizializacji';
            
        end

        % LoadButton button pushed function
        function load(app)
            app.mpc = loadcase(app.ListBox.Value);
            app.mpc = check_graph(app.mpc);
            if size(app.mpc.bus,1)~= max(app.mpc.bus(:,1));
                app.mpc = reNum(app.mpc);
            end
            %Wypełnienie pustych limitów danymi domyślnymi, jeśli potrzeba
            app.mpc = mk_default(app.mpc,0.900);
            app.mpr = solvePF(app.mpc);
            [app.hPlot,app.hGraph,app.hBar] = plotGrid_o(app.mpr);
            app.XData = app.hPlot.XData;
            app.YData = app.hPlot.YData;
            app.NodeName = app.hPlot.NodeLabel;
            app.TextArea.Value ='';
            cla
            
            res2excel(app.mpr, 'load.xlsx')
            msgbox('Pomyslnie zaladowano model do pamieci')
            
            
        end

        % DrawButton button pushed function
        function draw(app)
            rePlot(app.mpr,app.hGraph,app.XData, app.YData, app.NodeName)
            
        end

        % StartButton button pushed function
        function StartCascade(app)
            define_constants
            pause('on');
            flagMode = strjoin(cellstr(app.ModelKnob.Value));
            flagType = strjoin(cellstr(app.TypeKnob.Value));
            noBr = size(app.mpr.branch,1);
            ExistingDemand0 = sum(app.mpr.bus(app.mpr.bus(:, BUS_TYPE) ~= 4, PD));
            %             ExistingGeneration0 = sum(app.mpr.gen(:, PG));
            tempMpr = app.mpr;
            licz = 0;
            newTextCount = 1;
            
            
            while noBr>2 && tempMpr.success == 1 && ~isempty(tempMpr.gen(:, GEN_STATUS)>0)
                licz = licz +1;
                app.mpr = tempMpr;
                [lf, noBr] = calcFlowRatio(tempMpr);
                flag = any(lf>1);
                [app.hPlot,app.hBar] = rePlot(app.mpr, app.hGraph,app.XData, app.YData, app.NodeName);
                %
                app.hPlot.EdgeLabel={}
                if flag
                    overLoads = zeros(noBr,1);
                    overLoads(lf>1) = lf(lf>1);
                    switch flagType
                        case 'Wszystkie'
                            
                            pause(2)
                            app.mpr.branch(lf>1, BR_STATUS) = 0;
                            tempReport = app.mpr.branch(lf>1, :);
                            
                        case 'Pojedynczo'
                            
                            pause(2)
                            [maxValue,maxId] = max(overLoads);
                            app.mpr.branch(maxId, BR_STATUS) = 0;
                            tempReport = app.mpr.branch(maxId, :);
                            
                        case 'Recznie'
                            
                            [app.mpr, tempReport] = chooseOutage(app.mpr, app.hPlot, app.hGraph);
                    end
                else
                    [app.mpr, tempReport] = chooseOutage(app.mpr, app.hPlot, app.hGraph);
                end
                
                switch flagMode
                    case 'AC'
                        [tempMpr, isolData] = solvePF(app.mpr, 'AC');
                    case 'DC'
                        [tempMpr, isolData] = solvePF(app.mpr, 'DC');
                    case 'MIX'
                        [tempMpr, isolData] = solvePF(app.mpr, 'AC');
                        res2excel(tempMpr, sprintf(['licz_AC_' sprintf('%d',licz) '.xls']))
                        if (tempMpr.success == 0)
                            waitfor(msgbox('Przechodze na model DC'));
                            tempMpr = solvePF(app.mpr, 'DC');
                            res2excel(tempMpr, sprintf(['licz_DC_' sprintf('%d',licz) '.xls']))
                        end
                end
                
                report(licz).outages = tempReport;
                report(licz).outages(:,18) = sqrt(tempReport(:,PF).^2+tempReport(:,QF).^2)./tempReport(:,RATE_A);
                report(licz).success = tempMpr.success;
                report(licz).ExistingDemand = sum(tempMpr.bus(tempMpr.bus(:,BUS_TYPE) ~= 4, PD));
                report(licz).ExistingGeneration = sum(tempMpr.gen(:, PG));
                app.NinetyDegreeGauge.Value = report(licz).ExistingDemand / ExistingDemand0*100;
                
                for idResult = 1: size(report(licz).outages,1)
                    app.TextArea.Value{newTextCount} = sprintf('%d: Wylaczenie linii %d-%d o przeciazeniu %.2f.',...
                        licz, report(licz).outages(idResult,F_BUS),report(licz).outages(idResult,T_BUS),report(licz).outages(idResult,18));
                    newTextCount =  newTextCount+1;
                end
                if ~isempty(isolData.bus)
                    app.TextArea.Value{newTextCount} = sprintf('Brak mozliwosci pracy wyspowej, wyizolowano nastepujace wezly:');
                    newTextCount =  newTextCount+1;
                    app.TextArea.Value{newTextCount} = sprintf('%d (Pd= %d,Pg= %d MW), ', isolData.bus');
                    newTextCount =  newTextCount+1;
                    app.TextArea.Value{newTextCount} = sprintf('Sumarycznie wyizolowano %d odbiorcow o Pd = %d MW', ...
                        size(isolData.bus,1),sum(isolData.bus(:,2)));
                    newTextCount =  newTextCount+1;
                    app.TextArea.Value{newTextCount} = sprintf('Sumarycznie wyizolowano %d generatorow o Pg = %d MW', ...
                        size(isolData.gen,1),sum(isolData.gen(:,2)));
                    newTextCount =  newTextCount+1;
                end    
            end    
            if tempMpr.success == 1
                [app.hPlot,app.hBar] = rePlot(tempMpr,app.hGraph,app.XData, app.YData, app.NodeName );
            else
                [app.hPlot,app.hBar] = rePlot(app.mpr,app.hGraph,app.XData, app.YData, app.NodeName );
                report(licz).ExistingDemand = 0;
                app.NinetyDegreeGauge.Value = report(licz).ExistingDemand / ExistingDemand0*100;
            end    
            app.TextArea.Value{newTextCount} = sprintf('KONIEC KASKADY');
            
            app.result = report;
            clear isolData
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure;
            app.MainWindow.Name = 'KASKADA';
            setAutoResize(app, app.MainWindow, true)
            app.TabGroup = uitabgroup(app.MainWindow);
            app.TabGroup.Units = 'pixels';
            app.TabBox = uitab(app.TabGroup);
            app.TabBox.Units = 'pixels';
            app.TabBox.Title = 'System';
            app.LoadButton = uibutton(app.TabBox, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @load);
            app.LoadButton.Text = 'Wczytaj model';
            app.DrawButton = uibutton(app.TabBox, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @draw);
            app.DrawButton.Text = 'Rysuj system';
            app.ListBox = uilistbox(app.TabBox);
            app.ListBox.Items = {'case9', 'case39', 'case57', 'case89pegase', 'case118', 'case2383wp'};
            app.ListBox.Value = 'case9';
            app.Label = uilabel(app.TabBox);
            app.Label.Text = 'Nazwa modelu';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Kaskada';
            app.TypeKnob = uiknob(app.Tab, 'discrete');
            app.TypeKnob.Items = {'Wszystkie', 'Pojedynczo', 'Recznie'};
            app.TypeKnob.Value = 'Pojedynczo';
            app.StartButton = uibutton(app.Tab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartCascade);
            app.StartButton.Text = 'START';
            app.TextArea = uitextarea(app.Tab);
            app.NinetyDegreeGauge = uigauge(app.Tab, 'ninetydegree');
            app.NinetyDegreeGauge.Value = 100;
            app.Label2 = uilabel(app.Tab);
            app.Label2.Text = 'Poziom zasilenia';
            app.Label3 = uilabel(app.Tab);
            app.Label3.Text = 'odbiorców';
            app.ModelKnob = uiknob(app.Tab, 'discrete');
            app.ModelKnob.Items = {'AC', 'DC', 'MIX'};
            app.ModelKnob.Value = 'MIX';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CascadeGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end